classdef ChannelConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        ChannelModeButtonGroup       matlab.ui.container.ButtonGroup
        StandaloneButton             matlab.ui.control.RadioButton
        ServerButton                 matlab.ui.control.RadioButton
        ClientButton                 matlab.ui.control.RadioButton
        ChannelnameEditFieldLabel    matlab.ui.control.Label
        ChannelNameEditField         matlab.ui.control.EditField
        RefreshrateHzLabel           matlab.ui.control.Label
        RefreshRateEditField         matlab.ui.control.NumericEditField
        MessageprefixEditFieldLabel  matlab.ui.control.Label
        MessagePrefixEditField       matlab.ui.control.EditField
        UserfunctionDropDownLabel    matlab.ui.control.Label
        UserFunctionDropDown         matlab.ui.control.DropDown
        ClearuserfunctiondataButton  matlab.ui.control.Button
        UdpIpTab                     matlab.ui.container.Tab
        ClientTable                  matlab.ui.control.Table
        ServerIPEditFieldLabel       matlab.ui.control.Label
        ServerIPEditField            matlab.ui.control.EditField
        ServerportLabel              matlab.ui.control.Label
        ServerPortEditField          matlab.ui.control.NumericEditField
        LocalIPEditFieldLabel        matlab.ui.control.Label
        LocalIPEditField             matlab.ui.control.EditField
        LocalPortEditFieldLabel      matlab.ui.control.Label
        LocalPortEditField           matlab.ui.control.NumericEditField
        SerialTab                    matlab.ui.container.Tab
        RefreshButton                matlab.ui.control.Button
        SerialPortDropDownLabel      matlab.ui.control.Label
        SerialPortDropDown           matlab.ui.control.DropDown
        SerialProtocolButtonGroup    matlab.ui.container.ButtonGroup
        TransparentButton            matlab.ui.control.RadioButton
        XBeeAPIButton                matlab.ui.control.RadioButton
        BaudrateEditFieldLabel       matlab.ui.control.Label
        BaudRateEditField            matlab.ui.control.NumericEditField
        LoggingTab                   matlab.ui.container.Tab
        LogOutMsgCheckBox            matlab.ui.control.CheckBox
        AddTimeTagCheckBox           matlab.ui.control.CheckBox
        AddIOTagCheckBox             matlab.ui.control.CheckBox
        TagdelimiterEditFieldLabel   matlab.ui.control.Label
        TagDelimiterEditField        matlab.ui.control.EditField
        LogSimulatorPanel            matlab.ui.container.Panel
        LoggedmessageEditFieldLabel  matlab.ui.control.Label
        LoggedMessageEditField       matlab.ui.control.EditField
        MessageTypeButtonGroup       matlab.ui.container.ButtonGroup
        InputButton                  matlab.ui.control.RadioButton
        OutputButton                 matlab.ui.control.RadioButton
        RawmessageEditFieldLabel     matlab.ui.control.Label
        RawMessageEditField          matlab.ui.control.EditField
        EditmetadatafieldsButton     matlab.ui.control.Button
        SaveConfigButton             matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    properties
        vm                     % View-model of ChannelConfig
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.vm = ChannelConfigVM(app);
        end

        % Selection changed function: ChannelModeButtonGroup
        function ChannelModeButtonGroupSelectionChanged(app, event)
            app.vm.ChangeChannelModeUI();
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.vm.RefreshSerialPorts();
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            app.vm.SaveConfig();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end

        % Cell edit callback: ClientTable
        function ClientTableCellEdit(app, event)
            app.vm.OrganizeClientTable();
        end

        % Callback function: AddIOTagCheckBox, AddTimeTagCheckBox, 
        % LogOutMsgCheckBox, MessageTypeButtonGroup, 
        % RawMessageEditField, TagDelimiterEditField
        function UpdateLoggingSimulator(app, event)
            app.vm.SimulateLogging();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralTab.Units = 'pixels';
            app.ChannelModeButtonGroup = uibuttongroup(app.GeneralTab);
            app.ChannelModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelModeButtonGroupSelectionChanged, true);
            app.ChannelModeButtonGroup.Title = 'Channel mode';
            app.StandaloneButton = uiradiobutton(app.ChannelModeButtonGroup);
            app.StandaloneButton.Text = 'Standalone';
            app.StandaloneButton.Value = true;
            app.ServerButton = uiradiobutton(app.ChannelModeButtonGroup);
            app.ServerButton.Text = 'Server';
            app.ClientButton = uiradiobutton(app.ChannelModeButtonGroup);
            app.ClientButton.Text = 'Client';
            app.ChannelnameEditFieldLabel = uilabel(app.GeneralTab);
            app.ChannelnameEditFieldLabel.Text = 'Channel name';
            app.ChannelNameEditField = uieditfield(app.GeneralTab, 'text');
            app.RefreshrateHzLabel = uilabel(app.GeneralTab);
            app.RefreshrateHzLabel.Text = 'Refresh rate (Hz)';
            app.RefreshRateEditField = uieditfield(app.GeneralTab, 'numeric');
            app.RefreshRateEditField.Limits = [0.1 10];
            app.RefreshRateEditField.Value = 10;
            app.MessageprefixEditFieldLabel = uilabel(app.GeneralTab);
            app.MessageprefixEditFieldLabel.Text = 'Message prefix';
            app.MessagePrefixEditField = uieditfield(app.GeneralTab, 'text');
            app.UserfunctionDropDownLabel = uilabel(app.GeneralTab);
            app.UserfunctionDropDownLabel.Text = 'User function';
            app.UserFunctionDropDown = uidropdown(app.GeneralTab);
            app.UserFunctionDropDown.Items = {};
            app.UserFunctionDropDown.Value = {};
            app.ClearuserfunctiondataButton = uibutton(app.GeneralTab, 'push');
            app.ClearuserfunctiondataButton.Text = 'Clear user function data';
            app.UdpIpTab = uitab(app.TabGroup);
            app.UdpIpTab.Title = 'UDP/IP';
            app.UdpIpTab.Units = 'pixels';
            app.ClientTable = uitable(app.UdpIpTab);
            app.ClientTable.ColumnName = {'Client name'; 'Client IP'; 'Client port'};
            app.ClientTable.RowName = {};
            app.ClientTable.ColumnEditable = true;
            app.ClientTable.CellEditCallback = createCallbackFcn(app, @ClientTableCellEdit, true);
            app.ServerIPEditFieldLabel = uilabel(app.UdpIpTab);
            app.ServerIPEditFieldLabel.Text = 'Server IP';
            app.ServerIPEditField = uieditfield(app.UdpIpTab, 'text');
            app.ServerportLabel = uilabel(app.UdpIpTab);
            app.ServerportLabel.Text = 'Server port';
            app.ServerPortEditField = uieditfield(app.UdpIpTab, 'numeric');
            app.ServerPortEditField.Limits = [1 65535];
            app.ServerPortEditField.RoundFractionalValues = 'on';
            app.ServerPortEditField.ValueDisplayFormat = '%.0f';
            app.ServerPortEditField.Value = 8009;
            app.LocalIPEditFieldLabel = uilabel(app.UdpIpTab);
            app.LocalIPEditFieldLabel.Text = 'Local IP';
            app.LocalIPEditField = uieditfield(app.UdpIpTab, 'text');
            app.LocalIPEditField.Editable = 'off';
            app.LocalIPEditField.Value = '192.168.0.1';
            app.LocalPortEditFieldLabel = uilabel(app.UdpIpTab);
            app.LocalPortEditFieldLabel.Text = 'Local port';
            app.LocalPortEditField = uieditfield(app.UdpIpTab, 'numeric');
            app.LocalPortEditField.Limits = [1 65535];
            app.LocalPortEditField.RoundFractionalValues = 'on';
            app.LocalPortEditField.ValueDisplayFormat = '%.0f';
            app.LocalPortEditField.Value = 8009;
            app.SerialTab = uitab(app.TabGroup);
            app.SerialTab.Title = 'Serial';
            app.SerialTab.Units = 'pixels';
            app.RefreshButton = uibutton(app.SerialTab, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.SerialPortDropDownLabel = uilabel(app.SerialTab);
            app.SerialPortDropDownLabel.Text = 'Serial port';
            app.SerialPortDropDown = uidropdown(app.SerialTab);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.SerialProtocolButtonGroup = uibuttongroup(app.SerialTab);
            app.SerialProtocolButtonGroup.Title = 'Serial protocol';
            app.TransparentButton = uiradiobutton(app.SerialProtocolButtonGroup);
            app.TransparentButton.Text = 'Transparent';
            app.TransparentButton.Value = true;
            app.XBeeAPIButton = uiradiobutton(app.SerialProtocolButtonGroup);
            app.XBeeAPIButton.Text = 'XBeeAPI';
            app.BaudrateEditFieldLabel = uilabel(app.SerialTab);
            app.BaudrateEditFieldLabel.Text = 'Baud rate';
            app.BaudRateEditField = uieditfield(app.SerialTab, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.LoggingTab = uitab(app.TabGroup);
            app.LoggingTab.Title = 'Logging';
            app.LoggingTab.Units = 'pixels';
            app.LogOutMsgCheckBox = uicheckbox(app.LoggingTab);
            app.LogOutMsgCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.LogOutMsgCheckBox.Text = 'Log output messages';
            app.LogOutMsgCheckBox.Value = true;
            app.AddTimeTagCheckBox = uicheckbox(app.LoggingTab);
            app.AddTimeTagCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.AddTimeTagCheckBox.Text = 'Add system time tag';
            app.AddTimeTagCheckBox.Value = true;
            app.AddIOTagCheckBox = uicheckbox(app.LoggingTab);
            app.AddIOTagCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.AddIOTagCheckBox.Text = 'Add input/output tag';
            app.AddIOTagCheckBox.Value = true;
            app.TagdelimiterEditFieldLabel = uilabel(app.LoggingTab);
            app.TagdelimiterEditFieldLabel.Text = 'Tag delimiter';
            app.TagDelimiterEditField = uieditfield(app.LoggingTab, 'text');
            app.TagDelimiterEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.TagDelimiterEditField.Value = ',';
            app.LogSimulatorPanel = uipanel(app.LoggingTab);
            app.LogSimulatorPanel.Title = 'Logging simulator';
            app.LoggedmessageEditFieldLabel = uilabel(app.LogSimulatorPanel);
            app.LoggedmessageEditFieldLabel.Text = 'Logged message';
            app.LoggedMessageEditField = uieditfield(app.LogSimulatorPanel, 'text');
            app.LoggedMessageEditField.Editable = 'off';
            app.MessageTypeButtonGroup = uibuttongroup(app.LogSimulatorPanel);
            app.MessageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.InputButton = uiradiobutton(app.MessageTypeButtonGroup);
            app.InputButton.Text = 'Input';
            app.InputButton.Value = true;
            app.OutputButton = uiradiobutton(app.MessageTypeButtonGroup);
            app.OutputButton.Text = 'Output';
            app.RawmessageEditFieldLabel = uilabel(app.LogSimulatorPanel);
            app.RawmessageEditFieldLabel.Text = 'Raw message';
            app.RawMessageEditField = uieditfield(app.LogSimulatorPanel, 'text');
            app.RawMessageEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateLoggingSimulator, true);
            app.RawMessageEditField.Value = 'Hello planet!';
            app.EditmetadatafieldsButton = uibutton(app.LoggingTab, 'push');
            app.EditmetadatafieldsButton.Text = 'Edit metadata fields';
            app.SaveConfigButton = uibutton(app.UIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save configuraion';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ChannelConfig()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end