classdef SatellitesViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        CurrentChannelPanel          matlab.ui.container.Panel
        SaveLogButton                matlab.ui.control.Button
        DeleteLogButton              matlab.ui.control.Button
        OpenLogWindowButton          matlab.ui.control.Button
        AppendTimeCheckBox           matlab.ui.control.CheckBox
        EditMetadataButton           matlab.ui.control.Button
        FilenameLabel                matlab.ui.control.Label
        LogFileNameEdit              matlab.ui.control.EditField
        EnableCheckBox               matlab.ui.control.CheckBox
        ConfigureButton              matlab.ui.control.Button
        DeleteChannelButton          matlab.ui.control.Button
        CurrentchannelDropDownLabel  matlab.ui.control.Label
        CurrentChannelDropDown       matlab.ui.control.DropDown
        ChannelsPanel                matlab.ui.container.Panel
        ChannelTable                 matlab.ui.control.Table
        AddChannelButton             matlab.ui.control.Button
        SettingsPanel                matlab.ui.container.Panel
        LoadSettingsButton           matlab.ui.control.Button
        SaveSettingsButton           matlab.ui.control.Button
        LoadCmdGroupButton           matlab.ui.control.Button
        QuickCmdTab                  matlab.ui.container.Tab
        AboutTab                     matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
    end


    properties (Access = public)
        % The view-model of SatellitesViewer
        vm
        
        % Customable UI controls
        quickCmdEdits
        quickCmdButtons
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialize the view-model
            app.vm = SatellitesViewerVM(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % End all connections and close all windows
            if isa(app.vm, 'SatellitesViewerVM')
                app.vm.CloseApp();
            end
            
            app.delete();
            
        end

        % Button pushed function: LoadCmdGroupButton
        function LoadCmdGroupButtonButtonPushed(app, event)
            app.vm.LoadCmdGroup();
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonButtonPushed(app, event)
            app.vm.SaveLog();
        end

        % Button pushed function: DeleteLogButton
        function DeleteLogButtonPushed(app, event)
            app.vm.DeleteLog();
        end

        % Button pushed function: OpenLogWindowButton
        function OpenLogWindowButtonPushed(app, event)
            app.vm.ShowLogWindow();
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
            app.vm.AddChannel();
        end

        % Callback function
        function EnableCheckBoxValueChanged(app, event)
            app.EnableCheckBox.Value = app.vm.SwitchChannelEnable(app.EnableCheckBox.Value);
        end

        % Callback function
        function ConfigureButtonPushed(app, event)
            app.vm.ConfigureChannel();
        end

        % Callback function
        function DeleteChannelButtonPushed(app, event)
            app.vm.DeleteChannel();
        end

        % Value changed function: CurrentChannelDropDown
        function CurrentChannelDropDownValueChanged(app, event)
            app.vm.ChangeCurrentChannel();
        end

        % Button pushed function: EditMetadataButton
        function EditMetadataButtonPushed(app, event)
            %             app.vm.EditMetadata();
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.vm.SaveSettings();
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            app.vm.LoadSettings();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'SatellitesViewer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, false)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MainTab.Units = 'pixels';
            app.CurrentChannelPanel = uipanel(app.MainTab);
            app.CurrentChannelPanel.Title = 'Current Channel';
            app.SaveLogButton = uibutton(app.CurrentChannelPanel, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonButtonPushed, true);
            app.SaveLogButton.Text = 'Save log';
            app.DeleteLogButton = uibutton(app.CurrentChannelPanel, 'push');
            app.DeleteLogButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLogButtonPushed, true);
            app.DeleteLogButton.Text = 'Empty log';
            app.OpenLogWindowButton = uibutton(app.CurrentChannelPanel, 'push');
            app.OpenLogWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLogWindowButtonPushed, true);
            app.OpenLogWindowButton.Text = 'Show log window';
            app.AppendTimeCheckBox = uicheckbox(app.CurrentChannelPanel);
            app.AppendTimeCheckBox.Text = 'Append time';
            app.AppendTimeCheckBox.Value = true;
            app.EditMetadataButton = uibutton(app.CurrentChannelPanel, 'push');
            app.EditMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @EditMetadataButtonPushed, true);
            app.EditMetadataButton.Enable = 'off';
            app.EditMetadataButton.Text = 'Edit metadata';
            app.FilenameLabel = uilabel(app.CurrentChannelPanel);
            app.FilenameLabel.Text = 'File name';
            app.LogFileNameEdit = uieditfield(app.CurrentChannelPanel, 'text');
            app.EnableCheckBox = uicheckbox(app.CurrentChannelPanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.ConfigureButton = uibutton(app.CurrentChannelPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.DeleteChannelButton = uibutton(app.CurrentChannelPanel, 'push');
            app.DeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteChannelButtonPushed, true);
            app.DeleteChannelButton.Text = 'Delete';
            app.CurrentchannelDropDownLabel = uilabel(app.CurrentChannelPanel);
            app.CurrentchannelDropDownLabel.Text = 'Current channel';
            app.CurrentChannelDropDown = uidropdown(app.CurrentChannelPanel);
            app.CurrentChannelDropDown.Items = {};
            app.CurrentChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentChannelDropDownValueChanged, true);
            app.CurrentChannelDropDown.Value = {};
            app.ChannelsPanel = uipanel(app.MainTab);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelTable = uitable(app.ChannelsPanel);
            app.ChannelTable.ColumnName = {'Enable'; 'Name'; 'Port'; 'Mode'; 'Log'; 'User function'};
            app.ChannelTable.RowName = {};
            app.AddChannelButton = uibutton(app.ChannelsPanel, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = 'Add new channel';
            app.SettingsPanel = uipanel(app.MainTab);
            app.SettingsPanel.Title = 'Settings';
            app.LoadSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load settings';
            app.SaveSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save settings';
            app.LoadCmdGroupButton = uibutton(app.SettingsPanel, 'push');
            app.LoadCmdGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCmdGroupButtonButtonPushed, true);
            app.LoadCmdGroupButton.Text = 'Load command groups';
            app.QuickCmdTab = uitab(app.TabGroup);
            app.QuickCmdTab.Title = 'Quick Commands';
            app.QuickCmdTab.Units = 'pixels';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTab.Units = 'pixels';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SatellitesViewer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end